classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Image               matlab.ui.control.Image
        Image2              matlab.ui.control.Image
        InputButton         matlab.ui.control.Button
        ExampleButton       matlab.ui.control.Button
        OptionsButtonGroup  matlab.ui.container.ButtonGroup
        DefaultButton       matlab.ui.control.RadioButton
        ColorButton         matlab.ui.control.RadioButton
        SepiaBWButton       matlab.ui.control.RadioButton
        StartButton         matlab.ui.control.Button
        Image3              matlab.ui.control.Image
        SaveButton          matlab.ui.control.Button
    end

    
    properties (Access = public)
        Property string % Description
        Property2 string % Description
        
        Property3 string % Description
        Property4 string % Description
       
        Property5 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InputButton
        function InputButtonPushed(app, event)
            [FileName Path]=uigetfile({'*.jpg;*.jpeg;*.png'},'Abrir Imagen');
            if isequal(FileName,0)
                return
            else
                app.Image.ImageSource = strcat(Path,FileName);
            end
            
            app.Property = Path;
            app.Property2 = FileName;
        end

        % Button pushed function: ExampleButton
        function ExampleButtonPushed(app, event)
            [FileName Path]=uigetfile({'*.jpg;*.jpeg;*.png'},'Abrir Imagen');
            if isequal(FileName,0)
                return
            else
                app.Image2.ImageSource = strcat(Path,FileName);
            end
            app.Property3 = Path;
            app.Property4 = FileName;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            out = landscape(app.Property,app.Property3,app.Property2,app.Property4,app.ColorButton.Value,app.SepiaBWButton.Value);
            app.Image3.ImageSource = out;
            app.Property5 = out;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            [filename, pathname, filterindex] = uiputfile({'*.jpg';'*.jpeg';'*.png'});
            im=app.Property5;
            imwrite(im,strcat(pathname,filename));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image = uiimage(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            app.InputButton = uibutton(app.UIFigure, 'push');
            app.InputButton.ButtonPushedFcn = createCallbackFcn(app, @InputButtonPushed, true);
            app.InputButton.Text = 'Input';
            app.ExampleButton = uibutton(app.UIFigure, 'push');
            app.ExampleButton.ButtonPushedFcn = createCallbackFcn(app, @ExampleButtonPushed, true);
            app.ExampleButton.Text = 'Example';
            app.OptionsButtonGroup = uibuttongroup(app.UIFigure);
            app.OptionsButtonGroup.Title = 'Options';
            app.DefaultButton = uiradiobutton(app.OptionsButtonGroup);
            app.DefaultButton.Text = 'Default';
            app.DefaultButton.Value = true;
            app.ColorButton = uiradiobutton(app.OptionsButtonGroup);
            app.ColorButton.Text = 'Color';
            app.SepiaBWButton = uiradiobutton(app.OptionsButtonGroup);
            app.SepiaBWButton.Text = 'Sepia/BW';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Image3 = uiimage(app.UIFigure);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end